classdef image_compression_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image2          matlab.ui.control.Image
        EditField       matlab.ui.control.EditField
        CompressButton  matlab.ui.control.Button
        Slider          matlab.ui.control.Slider
        BrowseImageforCompressionButton  matlab.ui.control.Button
        Image3          matlab.ui.control.Image
        whiteBG         matlab.ui.control.Image
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slider.Visible="off";
            app.whiteBG.Visible="off";
            app.CompressButton.Visible="off";
            app.EditField.Visible="off";
            app.Image2.Visible="off";
            app.Image3.Visible="off";
           
            
            % Set custom property 'ButtonID' for the button
            %app.Button.UserData.ButtonID = 'Button1';
            app.BrowseImageforCompressionButton.UserData.image="";

        end

        % Button pushed function: BrowseImageforCompressionButton
        function UploadButtonPushed(app, event)

        
        [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, 'Select an image file');
        if isequal(file, 0)
            disp('User canceled image selection');
            return;
        end
        % Read the selected image file
        image = imread(fullfile(path, file));
        app.BrowseImageforCompressionButton.UserData.image=image;
        %app.BrowseImageforCompressionButton.Text="Select Another Image";
        app.BrowseImageforCompressionButton.Position=[130,100,132,39];
        app.BrowseImageforCompressionButton.FontWeight="bold";
        app.Image.Visible="off";
        app.Image2.Visible="on";
        app.Image3.ImageSource=image;
        app.Image3.Position=[389, 0, 252, 181];
            app.whiteBG.Visible="on";
        app.Slider.Visible='on';
        app.CompressButton.Visible='on';
            app.EditField.Visible="on";
            app.Image3.Visible="on";

        end

        % Button pushed function: CompressButton
        function CompressPressed(app, event)
N=int32(app.Slider.Value);
image=app.BrowseImageforCompressionButton.UserData.image;
title(['Image Quality x Singular Values']);
[error, compressedimage] = compressImage(image, N);
% Save the compressed image
imwrite(uint8(compressedimage), 'compressed_image.jpg');


% Display original image
subplot(1, 2, 1);
imshow(uint8(image), 'InitialMagnification', 'fit');
title('Original Image');

% Display compressed image
subplot(1, 2, 2);
imshow(uint8(compressedimage), 'InitialMagnification', 'fit');
title("Compressed Image with "+N+" singular values");


 %text(0.2, 0.01, "Compression Error: " + error + "%", ...
 %        'FontSize', 9, 'FontWeight', 'bold', 'Color', 'black', ...
  %       'HorizontalAlignment', 'center', 'VerticalAlignment', 'top', ...
   %      'Units', 'normalized');
        end

        % Value changed function: Slider
        function N(app, event)
            value = app.Slider.Value;
        end

        % Value changing function: Slider
        function NChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value="Number of Singular Values: "+int32(changingValue)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download.jpeg');
            app.whiteBG = uiimage(app.UIFigure);
            app.whiteBG.ImageSource = fullfile(pathToMLAPP, 'BG.png');
            app.Image3 = uiimage(app.UIFigure);
            app.BrowseImageforCompressionButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageforCompressionButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.BrowseImageforCompressionButton.Text = 'Browse Image for Compression';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 150];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @N, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @NChanging, true);
            app.Slider.Visible = 'off';
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressPressed, true);
            app.CompressButton.Visible = 'off';
            app.CompressButton.Text = 'Compress';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            app.EditField.Value = 'Select the number of singular values for compression';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img2.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_compression_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end